classdef UserInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FaceAppGalleryUIFigure  matlab.ui.Figure
        usernamePanel           matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        FiltersButtonGroup      matlab.ui.container.ButtonGroup
        MedianButton            matlab.ui.control.ToggleButton
        GaussianButton          matlab.ui.control.ToggleButton
        ZerophaseButton         matlab.ui.control.Button
        RotationButtonGroup     matlab.ui.container.ButtonGroup
        LeftButton              matlab.ui.control.StateButton
        RightButton             matlab.ui.control.StateButton
        ButtonGroup             matlab.ui.container.ButtonGroup
        SaveButton              matlab.ui.control.Button
        ResetButton             matlab.ui.control.Button
        LoadphotoButton         matlab.ui.control.Button
    end


    properties (Access = private)
        connexion; % FaceApp connexion window
        username; % name of connected user
        chosenImage; % image loaded by the user
        modifiedImage;
        filter = {'*.jpeg';'*.jpg';'*.png';'*.bmp';'*.gif';'*.tiff'};
        logo = imread('FaceAppLogo.jpg');
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ConnexionInterface, user)

              app.connexion = ConnexionInterface;
              app.username = user;
              app.usernamePanel.Title =user;
             app.UIAxes.XLim =[0,640];
             app.UIAxes.YLim =[0,480];
             axis(app.UIAxes,'image');
             imshow(app.logo, 'parent', app.UIAxes);
        end

        % Button pushed function: LoadphotoButton
        function loadPhotoPushed(app, event)
             [file,path]=uigetfile(app.filter, 'Select an image.');
              fichier = [path,file];
             app.chosenImage = imread(fichier);
             imshow(app.chosenImage, 'parent', app.UIAxes);
        end

        % Button pushed function: SaveButton
        function saveButtonChanged(app, event)
             uiputfile(app.filter, 'Select where to save your image');
        end

        % Button pushed function: ResetButton
        function resetButtonPushed(app, event)
           
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FaceAppGalleryUIFigure = uifigure;
            app.FaceAppGalleryUIFigure.Name = 'FaceApp - Gallery';
            app.usernamePanel = uipanel(app.FaceAppGalleryUIFigure);
            app.usernamePanel.Title = 'username';
            app.UIAxes = uiaxes(app.usernamePanel);
            title(app.UIAxes, 'Enjoy FaceApp!')
            app.FiltersButtonGroup = uibuttongroup(app.FaceAppGalleryUIFigure);
            app.FiltersButtonGroup.Title = 'Filters';
            app.MedianButton = uitogglebutton(app.FiltersButtonGroup);
            app.MedianButton.Text = 'Median';
            app.MedianButton.Value = true;
            app.GaussianButton = uitogglebutton(app.FiltersButtonGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.ZerophaseButton = uibutton(app.FiltersButtonGroup, 'push');
            app.ZerophaseButton.Text = 'Zero phase';
            app.RotationButtonGroup = uibuttongroup(app.FaceAppGalleryUIFigure);
            app.RotationButtonGroup.Title = 'Rotation';
            app.LeftButton = uibutton(app.RotationButtonGroup, 'state');
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.RotationButtonGroup, 'state');
            app.RightButton.Text = 'Right';
            app.ButtonGroup = uibuttongroup(app.FaceAppGalleryUIFigure);
            app.ButtonGroup.Title = 'Button Group';
            app.SaveButton = uibutton(app.ButtonGroup, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonChanged, true);
            app.SaveButton.Text = 'Save';
            app.ResetButton = uibutton(app.ButtonGroup, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.LoadphotoButton = uibutton(app.ButtonGroup, 'push');
            app.LoadphotoButton.ButtonPushedFcn = createCallbackFcn(app, @loadPhotoPushed, true);
            app.LoadphotoButton.Text = 'Load photo';
        end
    end

    methods (Access = public)

        % Construct app
        function app = UserInterface(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FaceAppGalleryUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FaceAppGalleryUIFigure)
        end
    end
end